classdef cdr_GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        CoefficientofRestitutionEditField  matlab.ui.control.EditField
        CoefficientofRestitutionEditFieldLabel  matlab.ui.control.Label
        InorOutEditField             matlab.ui.control.EditField
        InorOutLabel                 matlab.ui.control.Label
        SelectShotDropDown           matlab.ui.control.DropDown
        SelectShotLabel              matlab.ui.control.Label
        UIAxes                       matlab.ui.control.UIAxes
        RunButton                    matlab.ui.control.Button
        SinglesorDoublesButtonGroup  matlab.ui.container.ButtonGroup
        SinglesButton                matlab.ui.control.RadioButton
        DoublesButton                matlab.ui.control.RadioButton
        VolleyorServeButtonGroup     matlab.ui.container.ButtonGroup
        ServeButton                  matlab.ui.control.RadioButton
        VolleyButton                 matlab.ui.control.RadioButton
        InputParametersLabel         matlab.ui.control.Label
        OuputParametersLabel         matlab.ui.control.Label
        Lamp                         matlab.ui.control.Lamp
        CalculateButton              matlab.ui.control.Button
    end

    properties (Access = public)
        % DELETE LATER - KEEP AND UPDATE WHILE GUI IS W.I.P
        % app.lamp flashes depending on in/out mode
       
        MODE;           % 1 -> coefficient of restitution, 2 -> ball is inside or out
        MATCH_TYPE;     % 1 -> singles mode, -> 2 doubles mode
        SHOT_TYPE;      % 1 -> serve mode, 2 -> volley mode
        path;           % path to .dat file in datFiles folder; contains ball info

        isShotIn;
        restitutionCoeff;
        xCoeff;
        yCoeff;
        bounceT;
        gcf;            %figure

        % get 6 things back from matlab client
        % calls function with mode/match_type/shot_type/path to shot
        % outputs:
        % in/out 1 -> in, 0 -> out
        % coeff restitution
        % coeff x
        % coeff y
        % bounce t
        % figure

    end
    
    methods (Access = private)
        
    end
    
    methods (Access = public)
        
        function resetAxes(app, UIAxes)
            % Clear Axes
            cla(UIAxes);

            % Start Plotting the Court Lines
            hold(app.UIAxes, 'on');
            

            % Copy and paste in Line Piecewises
            
        end

        function plotTrajectory(app, UIAxes, xCoeff, yCoeff, bounceT)
            % Create polynomial to plot
            timeVector = 1:2000; % Evaulate x,y positions from 1-2000 ms 
            xValues = polyval(xCoeff, timeVector);
            yValues = polyval(yCoeff, timeVector);

            % Find Bounce Location
            xMarker = polyval(xCoeff, bounceT);
            yMarker = polyval(yCoeff, bounceT);

            % Start Plotting the trajectory
            hold(app.UIAxes, 'on');
            plot(app.UIAxes, xValues, yValues, "r")
            % Show bounce spot
            plot(app.UIAxes, xMarker, yMarker, 'ro', 'MarkerSize', 8, 'LineWidth',2);
            hold(app.UIAxes, 'off');


        end
    end
 

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.MODE = 1;
            app.MATCH_TYPE = 1;
            app.SHOT_TYPE = 1;
            app.path = "";
            app.InorOutEditField = "N/A";
            app.CoefficientofRestitutionEditField = "N/A";
            app.resetAxes(app, app.UIAxes)
        end

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
            % add comment later
            % reset field until new answer found
            app.InorOutEditField = "N/A";
            app.CoefficientofRestitutionEditField = "N/A";
            % Make Graph Blank again

            app.restitutionCoeff, app.isShotIn, app.xCoeff, app.yCoeff, app.bounceT, app.gcf = MatlabClientAPI(app.MODE, app.MATCH_TYPE, app.SHOT_TYPE, app.path);
            app.InorOutEditField = app.isShotIn;
            app.CoefficientofRestitutionEditField = "N/A";
            app.lotTrajectory(app, app.UIAxes, app.xCoeff, app.yCoeff, app.bounceT)

            % Show graph somehow
        end

        % Selection changed function: SinglesorDoublesButtonGroup
        function SinglesorDoublesButtonGroupSelectionChanged(app, event)
            % Selector for shot in single or doubles, returns value
            % MATCH_TYPE; 1 -> singles mode, -> 2 doubles mode
            % app.SinglesButton
            % app.DoublesButton
            selectedButton = app.SinglesorDoublesButtonGroup.SelectedObject;

            if selectedButton == app.SinglesButton      % 1 -> singles mode
                app.MATCH_TYPE = 1;                     
            elseif selectedButton == app.DoublesButton  % 2 -> doubles mode
                app.MATCH_TYPE = 2;
            end
        end

        % Selection changed function: VolleyorServeButtonGroup
        function VolleyorServeButtonGroupSelectionChanged(app, event)
            % Selector for serve or volley shot, returns value
            % SHOT_TYPE; 1 -> serve mode, 2 -> volley mode
            % app.ServeButton
            % app.VolleyButton
            selectedButton = app.VolleyorServeButtonGroup.SelectedObject;
            
            if selectedButton == app.ServeButton        % 1 -> serve mode
                app.SHOT_TYPE = 1;
            elseif selectedButton == app.VolleyButton   % 2 -> volley mode
                app.SHOT_TYPE = 2;
            end
        end

        % Value changed function: SelectShotDropDown
        function SelectShotDropDownValueChanged(app, event)
            % item values = Shot 1, Shot 2, Shot 3, Shot 4, Shot 5
            % item values can be manually added in the dropdown list using
            % the + button, and deleted using the - minus button
            % Case statement? Or grab number from shot # and append to call
            % to file location to grab .dat from datFiles

            selectedItem = app.SelectShotDropDown.Value;
            dataSetNum = selectedItem(5); % take string of item and grabs just the #
            if app.SHOT_TYPE == 1                                           % 1 -> serve mode
                app.path = "../datFiles/serve" + dataSetNum + ".dat";       % concats data set # to serve
            elseif app.SHOT_TYPE == 2                                       % 2 -> doubles mode
                app.path = "../datFiles/volley" + dataSetNum + ".dat";      % concats data set # to volley
            end
                
        end

        % Value changed function: InorOutEditField
        function InorOutEditFieldValueChanged(app, event)
            value = app.InorOutEditField.Value;
            if app.InorOutEditField.value == 1          % when ball is in
                app.lamp = [0 1 0];             %stays green 
            elseif app.InorOutEditField.value == 0          % when ball is out
                while (1)
                    app.lamp = [1 0 0];         % flash red
                    pause(50);                 %pause 100ms
                    app.lamp = [0 0 0];         %off (gray)
                    pause(50);   
                end
            else % any other situation
                app.lamp = [0 0 0]; %off
            end
  
        end

        % Button pushed function: CalculateButton
        function CalculateButtonPushed(app, event)
            app.InorOutEditField = "N/A";
            app.CoefficientofRestitutionEditField = "N/A";
            app.resetAxes(app, app.UIAxes)
            % Make Graph Blank again
            app.MODE = 1;
            app.MATCH_TYPE = 1;
            app.SHOT_TYPE = 1;
            app.path = "../datFiles/bounce.dat";
            
            app.restitutionCoeff, app.isShotIn, app.xCoeff, app.yCoeff, app.bounceT, app.gcf = MatlabClientAPI(app.MODE, app.MATCH_TYPE, app.SHOT_TYPE, app.path);
            app.CoefficientofRestitutionEditField = app.restitutionCoeff;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.CalculateButton = uibutton(app.UIFigure, 'push');
            app.CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
            app.CalculateButton.Text = 'Calculate';
            app.Lamp = uilamp(app.UIFigure);
            app.OuputParametersLabel = uilabel(app.UIFigure);
            app.OuputParametersLabel.Text = 'Ouput Parameters';
            app.InputParametersLabel = uilabel(app.UIFigure);
            app.InputParametersLabel.Text = 'Input Parameters';
            app.VolleyorServeButtonGroup = uibuttongroup(app.UIFigure);
            app.VolleyorServeButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @VolleyorServeButtonGroupSelectionChanged, true);
            app.VolleyorServeButtonGroup.Title = 'Volley or Serve?';
            app.VolleyButton = uiradiobutton(app.VolleyorServeButtonGroup);
            app.VolleyButton.Text = 'Volley';
            app.ServeButton = uiradiobutton(app.VolleyorServeButtonGroup);
            app.ServeButton.Text = 'Serve';
            app.ServeButton.Value = true;
            app.SinglesorDoublesButtonGroup = uibuttongroup(app.UIFigure);
            app.SinglesorDoublesButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @SinglesorDoublesButtonGroupSelectionChanged, true);
            app.SinglesorDoublesButtonGroup.Title = 'Singles or Doubles?';
            app.DoublesButton = uiradiobutton(app.SinglesorDoublesButtonGroup);
            app.DoublesButton.Text = 'Doubles';
            app.SinglesButton = uiradiobutton(app.SinglesorDoublesButtonGroup);
            app.SinglesButton.Text = 'Singles';
            app.SinglesButton.Value = true;
            app.RunButton = uibutton(app.UIFigure, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = 'Run';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Ball Trajectory')
            app.UIAxes.XLim = [-12 12];
            app.UIAxes.YLim = [-6 6];
            app.UIAxes.XTick = [];
            app.UIAxes.XTickLabel = '';
            app.UIAxes.YTick = [];
            app.SelectShotLabel = uilabel(app.UIFigure);
            app.SelectShotLabel.Text = 'Select Shot: ';
            app.SelectShotDropDown = uidropdown(app.UIFigure);
            app.SelectShotDropDown.Items = {'Shot 1', 'Shot 2', 'Shot 3', 'Shot 4', 'Shot 5', 'Shot 6', 'Shot 7'};
            app.SelectShotDropDown.ValueChangedFcn = createCallbackFcn(app, @SelectShotDropDownValueChanged, true);
            app.SelectShotDropDown.Placeholder = 'Shot 1';
            app.SelectShotDropDown.Value = 'Shot 1';
            app.InorOutLabel = uilabel(app.UIFigure);
            app.InorOutLabel.Text = 'In or Out?    ';
            app.InorOutEditField = uieditfield(app.UIFigure, 'text');
            app.InorOutEditField.ValueChangedFcn = createCallbackFcn(app, @InorOutEditFieldValueChanged, true);
            app.InorOutEditField.Value = 'N/A';
            app.CoefficientofRestitutionEditFieldLabel = uilabel(app.UIFigure);
            app.CoefficientofRestitutionEditFieldLabel.Text = 'Coefficient of Restitution';
            app.CoefficientofRestitutionEditField = uieditfield(app.UIFigure, 'text');
            app.CoefficientofRestitutionEditField.Value = 'N/A';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = cdr_GUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end